classdef SerialPortGPSApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        FileLoggingPanel          matlab.ui.container.Panel
        FileLoggingGrid           matlab.ui.container.GridLayout
        StopButton                matlab.ui.control.Button
        StartButton               matlab.ui.control.Button
        FileSelectButton          matlab.ui.control.Button
        FilenameEditField         matlab.ui.control.EditField
        FilenameEditField_2Label  matlab.ui.control.Label
        TabGroup                  matlab.ui.container.TabGroup
        MapViewTab                matlab.ui.container.Tab
        PlotViewTab               matlab.ui.container.Tab
        PlotGrid                  matlab.ui.container.GridLayout
        YDataDropDown             matlab.ui.control.DropDown
        YdataLabel                matlab.ui.control.Label
        XDataDropDown             matlab.ui.control.DropDown
        XDataLabel                matlab.ui.control.Label
        UIAxes                    matlab.ui.control.UIAxes
        LiveDataGrid              matlab.ui.container.GridLayout
        TemperatureField          matlab.ui.control.EditField
        TemperaturaTLabel         matlab.ui.control.Label
        PressureField             matlab.ui.control.EditField
        PresionPaLabel            matlab.ui.control.Label
        SpeedField                matlab.ui.control.EditField
        SpeedLabel                matlab.ui.control.Label
        AcelerationField          matlab.ui.control.EditField
        CourseField               matlab.ui.control.EditField
        CourseLabel               matlab.ui.control.Label
        AltitudeField             matlab.ui.control.EditField
        AltitudeLabel             matlab.ui.control.Label
        LongitudeField            matlab.ui.control.EditField
        LongitudeddLabel          matlab.ui.control.Label
        LatitudeField             matlab.ui.control.EditField
        LatitudeddLabel           matlab.ui.control.Label
        ConfigPanel               matlab.ui.container.Panel
        ConfigGrid                matlab.ui.container.GridLayout
        ConfigurationPanel        matlab.ui.container.Panel
        ConfigurationGrid         matlab.ui.container.GridLayout
        DisconnectButton          matlab.ui.control.Button
        ConnectButton             matlab.ui.control.Button
        BaudrateDropDown          matlab.ui.control.DropDown
        BaudRateLabel             matlab.ui.control.Label
        RefreshButton             matlab.ui.control.Button
        SerialportDropDown        matlab.ui.control.DropDown
        SerialportDropDownLabel   matlab.ui.control.Label
    end

    properties (Access = private)
        % App Flags
        ChecksumAlert = false           % Display warning for invalid checksum

        % Object Handles
        Plot                            % Handle to "Plot View" line object

        % File Selection and Logging
        %Filename = "GPS_data.txt"       % Default file name for logging
       % FilePath = pwd                  % Default file path for logging
        %RawFilename = "GPS_data_raw.txt"% Default name for raw logging file
        SerialPort                      % Handle to serialport object
        Logging = false                 % Boolean for whether we are logging to file or not
        FileInitialized = false
        CycleEnd

        % Live Updating Variables (latest point)
        FixType double
        Satellites double
        Latitude double
        Longitude double
        Altitude double
        Course double
        Speed double
        HDOP double
        UTCTime
        UTCDate

        FixDict = dictionary(0:6, ...
            ["No Fix", ...
            "GPS fix", ...
            "Differential GPS fix", ...
            "Fix not valid", ...
            "Fix not valid", ...
            "Fix not valid", ...
            "Dead reckoning"]);

        % Data saved over time
        LatitudeTrack                   % For "Map View" latitude when logging
        LongitudeTrack                  % For "Map View" longitude when logging
        NMEARaw                         % For "NMEA Log" tab
        DataTable                       % For last 10 minutes of "live updating" data
        LastMinutes = 10                % Number of latest minutes to preserve
        LastLines = 500                 % Number of latest lines to preserve
        TableVariables = {              % Header for data table
            'ComputerTime', ...
            'FixType', ...
            'Latitude (dd)', ...
            'Longitude (dd)', ...
            'Satellites', ...
            'Altitude (m)', ...
            'HDOP', ...
            'Course (°True N)', ...
            'Speed (km/h)', ...
            'UTCDate', ...
            'UTCTime'};
    end

    methods (Access = private)

        function serialportCallback(app,obj,evt)
            %serialportCallback serves as the callback when new data is
            %   received by the serialport object, as set by configureCallback
            %   This callback controls the app flow based on the app states

            % Handle Data state
            inputline = readline(obj);
            % Extract callback timestamp from evt data
            eventtime = datetime(evt.AbsTime,Format='yyyy-MM-dd''T''HH:mm:ss.SSS');

            % Process Data state
            latestdata = processData(app,inputline,eventtime);

            if app.Logging
                % Log Data state
                logData(app,inputline,latestdata);
            end

            % Update Live View state
            updateLiveView(app)
        end

        function newrow = processData(app,inputline,dt)
            %processData Read and processes the newest line,
            %   save processed data to app properties for later use.
            %   This includes all of the "Live Data", "Track Data", meaning
            %   data saved over time, and the newest LastLines number of NMEA
            %   raw data sentences

            newrow = {dt};

            % Run checksum for inputline; if checksum is invalid, do not
            % process the line (return early)
            if ~validateChecksum(app,inputline)
                if app.ChecksumAlert
                    warning("Checksum is wrong for " + inputline);
                end
            end

            % Parse sentences
            if contains(inputline,"GPGGA") % Parse GGA sentence and save its output to app properties
                results = parseGGA(app,inputline);
                updateAppProperties(app,"GPGGA",results);
            elseif contains(inputline,"GPRMC") % Parse RMC sentence and save its output to app properties
                results = parseRMC(app,inputline);
                updateAppProperties(app,"GPRMC",results);

                % Update collected data over time if GPGGA sentence was also processed
                if ~isempty(app.Latitude)
                    newrow = {newrow{1}, app.FixType, app.Latitude, app.Longitude, app.Satellites, app.Altitude, app.HDOP, app.Course, app.Speed, app.UTCDate, app.UTCTime};
                    app.DataTable = [app.DataTable; newrow];

                    % Delete times older than 10 minutes
                    rowsToKeep = minutes(datetime("now") - app.DataTable.ComputerTime) < app.LastMinutes;
                    app.DataTable = app.DataTable(rowsToKeep,:);
                end
            end         
        end

        function lat = latToMap(~,inputlat,dir)
            %latToMap is a helper function to convert the latitude from the
            %   NMEA data (minute/second degrees) to decimal degrees.
            %   NMEA format is ddmm.mmmm, so use the degrees and minutes to
            %   convert to decimal degrees.

            if isempty(inputlat)
                lat = NaN;
                return
            end
            % Extract degrees from first 2 characters
            deg = str2double(extractBefore(inputlat,3));
            % Extract minutes from all characters following the 2nd
            min = str2double(extractAfter(inputlat,2));
            lat = deg + min/60;
            if dir == "S" % if direction is South
                lat = -lat;
            end
        end

        function long = longToMap(~,inputlong,dir)
            %longToMap is a helper function to convert the longitude from the
            %   NMEA data (minute/second degrees) to decimal degrees.
            %   NMEA format is (d)ddmm.mmmm, so use the degrees and minutes to
            %   convert to decimal degrees.

            if isempty(inputlong)
                long = NaN;
                return
            end
            % Extract degrees from first 3 characters
            deg = str2double(extractBefore(inputlong,4));
            % Extract minutes from all characters following the 3rd
            min = str2double(extractAfter(inputlong,3));
            long = deg + min/60;
            if dir == "W" % ifs direction is West
                long = -long;
            end
        end
        function updateAppProperties(app,sentence,values)
            %updateAppProperties Update the app properties for the input
            %   sentence based on the given values. For example, if sentence
            %   was "GPGGA", then the GPGGA-related app properties will be
            %   updated. values contains a struct of the necessary data.
            switch sentence
                case "GPGGA"
                    app.FixType = values.FixType;
                    app.Satellites = values.Satellites;
                    if app.Logging
                        % If Logging, preserve data for map
                        app.LatitudeTrack(end+1) = values.Latitude;
                        app.LongitudeTrack(end+1) = values.Longitude;
                    end
                    app.Latitude = values.Latitude;
                    app.Longitude = values.Longitude;
                    app.Altitude = values.Altitude;
                    app.HDOP = values.HDOP;
                case "GPRMC"
                    app.Course = values.Course;
                    app.Speed = values.Speed;
                    app.UTCDate = values.UTCDate;
                    app.UTCTime = values.UTCTime;
                    % Since the GPRMC line is last, flag end of data cycle
                    app.CycleEnd = 1;
            end
        end

        function updateLiveView(app)
            %updateLiveView will update the Live Data view and various
            %   tabs (depending on which tab is open) to match the "current data"

            if app.CycleEnd % Can update everything if at the end of a data cycle
                if app.FixType == 0 % If 0, there is no fix. Clear all Live Data fields
                    clearLiveDataFields(app);
                else % If not 0, then fill in details
                    updateLiveDataFields(app);
                end
                % Update graphics based on open tab group
                switch app.TabGroup.SelectedTab.Title
                    case "Map View"
                        updateMapView(app,app.Logging);
                    case "Plot View"
                        updatePlotView(app);
                end
                app.CycleEnd = 0; % Reset the cycle
                           
            end
        end

        function setAppState(app,state)
            %setAppState enables and disables buttons based on the state that
            %   the app is in

            switch state
                case "DeviceSelection"
                    % Only connect, baud rate, serial port, and file name
                    % select are enabled disable Start logging button
                    app.SerialportDropDown.Enable = "on";
                    app.BaudrateDropDown.Enable = "on";
                    app.ConnectButton.Enable = "on";
                    app.RefreshButton.Enable = "on";

                    app.DisconnectButton.Enable = "off";
                    app.StartButton.Enable = "off";
                    app.StopButton.Enable = "off";
                    app.XDataDropDown.Enable = "off";
                    app.YDataDropDown.Enable = "off";
                  %  app.FilterByDropDown.Enable = "off";
                case "Connected"
                    % Enable everything, disable the "configuration"
                    % options (except for disconnect)
                    app.DisconnectButton.Enable = "on";
                    app.StartButton.Enable = "on";
                    app.XDataDropDown.Enable = "on";
                    app.YDataDropDown.Enable = "on";
                    app.FilterByDropDown.Enable = "on";

                    app.SerialportDropDown.Enable = "off";
                    app.BaudrateDropDown.Enable = "off";
                    app.ConnectButton.Enable = "off";
                    app.StopButton.Enable = "off";
                    app.RefreshButton.Enable = "off";
                case "WaitForData"
                    % Not logging
                    app.DisconnectButton.Enable = "on";
                    app.StartButton.Enable = "on";
                    app.XDataDropDown.Enable = "on";
                    app.YDataDropDown.Enable = "on";
                    app.FilterByDropDown.Enable = "on";
                    app.FilenameEditField.Enable = "on";

                    app.SerialportDropDown.Enable = "off";
                    app.BaudrateDropDown.Enable = "off";
                    app.ConnectButton.Enable = "off";
                    app.StopButton.Enable = "off";
                    app.RefreshButton.Enable = "off";
                case "Logging"
                    app.DisconnectButton.Enable = "on";
                    app.StopButton.Enable = "on";
                    app.XDataDropDown.Enable = "on";
                    app.YDataDropDown.Enable = "on";
                    app.FilterByDropDown.Enable = "on";

                    app.SerialportDropDown.Enable = "off";
                    app.BaudrateDropDown.Enable = "off";
                    app.ConnectButton.Enable = "off";
                    app.StartButton.Enable = "off";
                    app.FilenameEditField.Enable = "off";
                    app.RefreshButton.Enable = "off";
            end
        end

        function results = validateChecksum(~,inputline)
            %validateChecksum confirms that the latest line has a valid
            %   checksum calculation

            if ~startsWith(inputline,"$")
                results = 0;
                return
            end
            splitline = split(inputline,["$" "*"]);
            checksumline = uint8(splitline{2});
            expected = hex2dec(splitline(3));
            checksum = uint8(0);
            % Iterate through each character in checksumline, executing a
            % bit-wise XOR across all characters to validate the checksum
            for character = checksumline
                checksum = bitxor(checksum,character);
            end
            results = checksum == expected;
        end

        
        function updateMapView(app,logging)
            %updateMapView is a helper function for the Map View tab

            if logging
                latdata = app.LatitudeTrack;
                longdata = app.LongitudeTrack;
            else
                latdata = app.Latitude;
                longdata = app.Longitude;
            end
            set(app.MapViewPlot,"LatitudeData",latdata,"LongitudeData",longdata);
            geolimits(app.MapViewAxes,app.GeolimitsDropDown.Value)
        end

        function updatePlotView(app)
            %updatePlotView is a helper function for the Plot View tab

            filter = seconds(datetime("now")-app.DataTable.ComputerTime) < app.XDataDropDown.Value;
            x = app.DataTable.ComputerTime(filter);
            y = app.DataTable{filter,app.YDataDropDown.Value};
            set(app.Plot,"XData",x,"YData",y);
        end


        function datastruct = parseGGA(app,inputline)
            %parseGGA is a helper function to read data from GPGGA sentences
            %    parseGGA takes in a serialport line beginning with "$GPGGA"
            %    and returns the latitude, longitude, HDOP, altitude, and number
            %    of satellites in use as a struct.
            %    For example, consider the following line:
            %    $GPGGA,170928.000,4217.9742,N,07121.1046,W,2,07,1.70,66.6,M,-33.8,M,0000,0000*64
            %       (1) $GPGGA      Sentence type
            %       (2) 170928.000  UTC time
            %       (3) 4217.9742   Latitude
            %       (4) N           North/South, latitude direction
            %       (5) 07121.1046  Longitude
            %       (6) W           East/West, longitude direction
            %       (7) 2           Quality indicator/fix type
            %       (8) 07          Number of satellites in use
            %       (9) 1.70        HDOP
            %       (10) 66.6       Altitude

            datastruct = struct("Latitude",NaN,"Longitude",NaN,"FixType",NaN,"HDOP",NaN,"Altitude",NaN,"Satellites",NaN);
            splitdata = split(inputline,",");
            datastruct.FixType = str2double(splitdata{7});
            if datastruct.FixType > 0
                datastruct.Latitude = latToMap(app,splitdata{3},splitdata{4});
                datastruct.Longitude = longToMap(app,splitdata{5},splitdata{6});
                datastruct.HDOP = str2double(splitdata{9});
                datastruct.Altitude = str2double(splitdata{10});
            end
            datastruct.Satellites = str2double(splitdata{8});
        end

        function datastruct = parseRMC(app,inputline)
            %parseRMC is a helper function to read data from GPRMC sentences
            %    parseRMC takes in a serialport line beginning with "$GPRMC"
            %    and returns the speed, course, UTC date, and UTC time from the
            %    GPS as a struct.
            %    For example, consider the following line:
            %    $GPRMC,170926.000,A,4217.9736,N,07121.1043,W,0.56,351.07,010124,,,D*78
            %       (1) $GPRMC      Sentence type
            %       (2) 170926.000  UTC time
            %       (3) A           Position status
            %       (4) 4217.9736   Latitude
            %       (5) N           Latitude direction
            %       (6) 07121.1043  Longitude
            %       (7) W           Longitude direction
            %       (8) 0.56        Speed over ground in knots
            %       (9) 351.07      Course in degrees True
            %       (10) 010124     UTC date

            datastruct = struct("Course",NaN,"Speed",NaN,"UTCDate",NaN,"UTCTime",NaN);
            splitdata = split(inputline,",");
            if splitdata{3} == "A" % If there is a fix, indicated by status "A" for active, "V" for void
                datastruct.Speed = knotsToKMH(app,splitdata{8});
                datastruct.Course = str2double(splitdata{9});
            end
            if ~isempty(splitdata{10}) && ~isempty(splitdata{2}) % Only proceed if there is a date and time
                if contains(splitdata{2},".")
                    % Time with fractional seconds
                    timeFormat = "ddMMyy'T'HHmmss.SSS";
                else
                    % Time without fractional seconds
                    timeFormat = "ddMMyy'T'HHmmss";
                end
                dt = datetime([splitdata{10} 'T' splitdata{2}],InputFormat=timeFormat);
                splittime = split(string(dt));
                datastruct.UTCDate = splittime(1);
                datastruct.UTCTime = splittime(2);
            end
        end

        function logData(app,inputline,row)
            %logData Represents the Log Data state for app. If FileInitialized is
            %   false, initialize files and log to file. Otherwise, just log to
            %   file

            if ~app.FileInitialized
                initializeProcessedDataFile(app);
            end
            logToFile(app,inputline,row);
        end

        function logToFile(app,inputline,row)
            %logToFile Write to raw data file and processed data file. Because
            %   inputline is a single line, use writelines for the raw file.
            %   Because row is a cell array, use writecell for processed file.

            % Log raw data
            dt = row{1};
            writelines(string(dt)+" "+inputline,app.RawFilename,WriteMode="append")

            % Log parsed data
            if app.CycleEnd
                writecell(row,app.FilenameEditField.Value,WriteMode="append")
            end
        end

        function initializeProcessedDataFile(app)
            %initializeProcessedDataFile Write header to file that logs all
            %   data (not raw file). If there is an error, disable logging
            %   and display error. Allow file logging with FileInitialized.

            try
                writecell(app.TableVariables,app.FilenameEditField.Value,WriteMode="append")
                app.FileInitialized = true;
            catch ME
                uialert(app.UIFigure,ME.message,"File Error",Interpreter="html")
                app.Logging = false;
            end
        end

        function [processedfile, rawfile] = createFileName(app,filename)
            %createFileName Check if the file name and corresponding raw file
            %   name meets expected format, meaning its extension is .TXT and
            %   it is not an already existing file. If the file name exists,
            %   return new, auto-indexed name.
            %   For example, if '<datafolder>\GPS_data.txt' exists, return the
            %   next indexed file name in the sequence that does not exist,
            %   such as '<datafolder>\GPS_data_2.txt'

            [filepath, name, ext] = fileparts(filename);
            if ~strcmpi(ext,".csv")
                uialert(app.UIFigure,"File must be in .CSV format","File Log Error")
                ext = ".csv";
            end
            ind = 0;
            processedfile = fullfile(filepath,strcat(name,ext));
            rawfile = fullfile(filepath,strcat(name,"_raw",ext));
            while isfile(processedfile) || isfile(rawfile)
                ind = ind + 1;
                processedfile = fullfile(filepath,strcat(name,"_",num2str(ind),ext));
                rawfile = fullfile(filepath,strcat(name,"_",num2str(ind),"_raw",ext));
            end
        end

        function updateLiveDataFields(app)
            %updateLiveDataFields Set all of the edit fields in the Live Data
            %   view section of the app to their respective app property
            %   values. Enable the lamp indicating GPS status (valid fix)

            app.GPSStatusLamp.Color = [0 1 0];
            app.GPSStatusField.Value = app.FixDict(app.FixType);
            app.SatellitesField.Value = string(app.Satellites);
            app.LatitudeField.Value = string(app.Latitude);
            app.LongitudeField.Value = string(app.Longitude);
            app.AltitudeField.Value = string(app.Altitude);
            app.AcelerationField.Value = string(app.Course);
            app.SpeedField.Value = string(app.Speed);
            app.PressureField.Value = string(app.HDOP);
            app.TemperatureField.Value = app.UTCDate;
            app.TimeField.Value = app.UTCTime;
        end

        function clearLiveDataFields(app)
            %clearLiveDataFields Set all of the edit fields in the Live Data
            %   view section of the app to be empty values. Disable the lamp
            %   indicating GPS status (no fix), since there is no data to show.

            app.GPSStatusLamp.Color = [0.9 0.9 0.9];
            app.GPSStatusField.Value = app.FixDict(0);
            app.SatellitesField.Value = "-";
            app.LatitudeField.Value = "-";
            app.LongitudeField.Value = "-";
            app.AltitudeField.Value = "-";
            app.AcelerationField.Value = "-";
            app.SpeedField.Value = "-";
            app.PressureField.Value = "-";
            app.TemperatureField.Value = app.UTCDate;
            app.TimeField.Value = app.UTCTime;
        end

        function resetData(app)
            %resetData Reset all of the saved data from the app properties.

            app.FixType = 0;
            app.Satellites = 0;
            app.Latitude = 0;
            app.Longitude = 0;
            app.Altitude = 0;
            app.Course = 0;
            app.Speed = 0;
            app.HDOP = 0;
            app.UTCTime = "";
            app.UTCDate = "";
            app.NMEARaw = [];
            app.Logging = false;
            app.FileInitialized = false;
            app.LatitudeTrack = [];
            app.LongitudeTrack = [];
            app.DataTable = table(datetime("now"),0,0,0,0,0,0,0,0,0,0,VariableNames=app.TableVariables);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % Set up other components
            app.SerialportDropDown.Items = cellstr(["" serialportlist]);
            %app.FilenameEditField.Value = fullfile(app.FilePath,app.Filename);
            app.Plot = plot(app.UIAxes,datetime("now"),0);
            app.UIAxes.XAxis.SecondaryLabelFormat = "MMM d";
            ylim(app.UIAxes,"padded")
            xlim(app.UIAxes,"padded")
            setAppState(app,"DeviceSelection");
        end

        % Callback function: not associated with a component
        function ConnectButtonPushed(app, event)
            setAppState(app,"Connected");
            drawnow

            % Validate selected serial port device
            try
                app.SerialPort = serialport(app.SerialportDropDown.Value,str2double(app.BaudrateDropDown.Value));
            catch ME
                uialert(app.UIFigure,ME.message,"Serialport Connection Error",Interpreter="html")
                setAppState(app,"DeviceSelection");
                return
            end

            % Set terminator and callback
            configureTerminator(app.SerialPort,"CR/LF")
            configureCallback(app.SerialPort,"terminator", @(obj,event) serialportCallback(app,obj,event))

            % Reset app properties
            resetData(app);
        end

        % Callback function: not associated with a component
        function RefreshButtonPushed(app, event)
            app.SerialportDropDown.Items = cellstr(["" serialportlist]);
        end

        % Callback function: not associated with a component
        function DisconnectButtonPushed(app, event)
            delete(app.SerialPort)
            StopButtonPushed(app);
            setAppState(app,"DeviceSelection");
        end

        % Callback function
        function FileSelectButtonPushed(app, event)
            [app.Filename, app.FilePath] = uiputfile('*.csv', 'Select Save Location');
            figure(app.UIFigure)
            if ~isequal(app.Filename, 0) && ~isequal(app.FilePath, 0)
            app.FilenameEditField.Value = fullfile(app.FilePath, app.Filename);
            app.FileInitialized = true;
            disp(['Data will be logged to: ', fullfile(app.FilePath, app.Filename)]);
            else
             uialert(app.UIFigure, 'No valid save location selected.', 'Save Location Error');
            end
        end

        % Callback function
        function StartButtonPushed(app, event)
            if ~app.FileInitialized
              [name, raw] = createFileName(app, app.FilenameEditField.Value);
              app.FilenameEditField.Value = name;
              app.RawFilename = raw;
            end
    
        if ~isempty(app.Filename)
            app.Logging = true;
            app.FileID = fopen(fullfile(app.FilePath, app.Filename), 'a');
        
        if app.FileID == -1
            uialert(app.UIFigure, 'Failed to open the file for logging.', 'File Error');
            return;
        end

        % Reset Tracking Data
        app.LatitudeTrack = [];
        app.LongitudeTrack = [];

        setAppState(app, "Logging");
        disp('Logging started...');
        else
        uialert(app.UIFigure, 'Please select a valid folder/file first.', 'File Not Found');
        end
        end

        % Callback function: not associated with a component
        function TabGroupSelectionChanged(app, event)
            switch app.TabGroup.SelectedTab
                case "MapViewTab"
                    updateMapView(app,app.Logging);
                case "PlotViewTab"
                    updatePlotView(app);                
            end
        end

        % Callback function: not associated with a component
        function XDataDropDownValueChanged(app, event)
            updatePlotView(app);
        end

        % Callback function: not associated with a component
        function YDataDropDownValueChanged(app, event)
            ylabel(app.UIAxes,app.YDataDropDown.Value)
            updatePlotView(app);
        end

        % Callback function
        function StopButtonPushed(app, event)
            if app.Logging && ~isempty(app.FileID)
                fclose(app.FileID);
                app.Logging = false;
                setAppState(app, "WaitForData");
                disp('Logging stopped.');
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            selection = "";
            if ~isempty(app.SerialPort) && isvalid(app.SerialPort)
                selection = uiconfirm(app.UIFigure,"Close app and serial port connection?","Confirm Close");
            end
            if selection ~= "Cancel"
                delete(app)
            end
        end

        % Callback function
        function FilenameEditFieldValueChanged(app, event)
            [name, raw] = createFileName(app,app.FilenameEditField.Value);
            % Reset FileInitialized only when the file name is changed
            % if the file name is not changed, continue to write to the
            % existing file, appending new data to the end
            app.FileInitialized = false;
            app.FilenameEditField.Value = name;
            app.RawFilename = raw;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.ConfigPanel = uipanel(app.GridLayout);
            app.ConfigGrid = uigridlayout(app.ConfigPanel);
            app.ConfigurationPanel = uipanel(app.ConfigGrid);
            app.ConfigurationPanel.Title = 'Configuration';
            app.ConfigurationGrid = uigridlayout(app.ConfigurationPanel);
            app.SerialportDropDownLabel = uilabel(app.ConfigurationGrid);
            app.SerialportDropDownLabel.Text = 'Serial port';
            app.SerialportDropDown = uidropdown(app.ConfigurationGrid);
            app.SerialportDropDown.Items = {};
            app.SerialportDropDown.Placeholder = 'Select...';
            app.SerialportDropDown.Value = {};
            app.RefreshButton = uibutton(app.ConfigurationGrid, 'push');
            app.RefreshButton.Icon = fullfile(pathToMLAPP, 'refresh.svg');
            app.RefreshButton.Text = '';
            app.BaudRateLabel = uilabel(app.ConfigurationGrid);
            app.BaudRateLabel.Text = 'Baud rate';
            app.BaudrateDropDown = uidropdown(app.ConfigurationGrid);
            app.BaudrateDropDown.Items = {'4800', '9600', '19200', '115200'};
            app.BaudrateDropDown.Editable = 'on';
            app.BaudrateDropDown.Placeholder = 'Select...';
            app.BaudrateDropDown.Value = '4800';
            app.ConnectButton = uibutton(app.ConfigurationGrid, 'push');
            app.ConnectButton.Text = 'Connect';
            app.DisconnectButton = uibutton(app.ConfigurationGrid, 'push');
            app.DisconnectButton.Enable = 'off';
            app.DisconnectButton.Text = 'Disconnect';
            app.LiveDataGrid = uigridlayout(app.GridLayout);
            app.LatitudeddLabel = uilabel(app.LiveDataGrid);
            app.LatitudeddLabel.Text = 'Latitude (dd):';
            app.LatitudeField = uieditfield(app.LiveDataGrid, 'text');
            app.LatitudeField.Editable = 'off';
            app.LatitudeField.Value = '-';
            app.LongitudeddLabel = uilabel(app.LiveDataGrid);
            app.LongitudeddLabel.Text = 'Longitude (dd):';
            app.LongitudeField = uieditfield(app.LiveDataGrid, 'text');
            app.LongitudeField.Editable = 'off';
            app.LongitudeField.Value = '-';
            app.AltitudeLabel = uilabel(app.LiveDataGrid);
            app.AltitudeLabel.Text = 'Altitude (m):';
            app.AltitudeField = uieditfield(app.LiveDataGrid, 'text');
            app.AltitudeField.Editable = 'off';
            app.AltitudeField.Value = '-';
            app.CourseLabel = uilabel(app.LiveDataGrid);
            app.CourseLabel.Text = 'Velocidad (m/s):';
            app.CourseField = uieditfield(app.LiveDataGrid, 'text');
            app.CourseField.Editable = 'off';
            app.CourseField.Value = '-';
            app.AcelerationField = uieditfield(app.LiveDataGrid, 'text');
            app.AcelerationField.Editable = 'off';
            app.AcelerationField.Value = '-';
            app.SpeedLabel = uilabel(app.LiveDataGrid);
            app.SpeedLabel.Text = 'Aceleración (m/s2):';
            app.SpeedField = uieditfield(app.LiveDataGrid, 'text');
            app.SpeedField.Editable = 'off';
            app.SpeedField.Value = '-';
            app.PresionPaLabel = uilabel(app.LiveDataGrid);
            app.PresionPaLabel.Text = 'Presion (Pa):';
            app.PressureField = uieditfield(app.LiveDataGrid, 'text');
            app.PressureField.Editable = 'off';
            app.PressureField.Value = '-';
            app.TemperaturaTLabel = uilabel(app.LiveDataGrid);
            app.TemperaturaTLabel.Text = 'Temperatura (T):';
            app.TemperatureField = uieditfield(app.LiveDataGrid, 'text');
            app.TemperatureField.Editable = 'off';
            app.TemperatureField.Value = '-';
            app.TabGroup = uitabgroup(app.GridLayout);
            app.MapViewTab = uitab(app.TabGroup);
            app.MapViewTab.Title = 'Map View';
            app.PlotViewTab = uitab(app.TabGroup);
            app.PlotViewTab.Title = 'Plot View';
            app.PlotGrid = uigridlayout(app.PlotViewTab);
            app.UIAxes = uiaxes(app.PlotGrid);
            title(app.UIAxes, 'Live Data over Time')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Satellites')
            zlabel(app.UIAxes, 'Z')
            app.XDataLabel = uilabel(app.PlotGrid);
            app.XDataLabel.Text = 'X data';
            app.XDataDropDown = uidropdown(app.PlotGrid);
            app.XDataDropDown.Items = {'30 sec', '1 min', '5 min'};
            app.XDataDropDown.ItemsData = {'30', '60', '300'};
            app.XDataDropDown.Value = '30';
            app.YdataLabel = uilabel(app.PlotGrid);
            app.YdataLabel.Text = 'Y data';
            app.YDataDropDown = uidropdown(app.PlotGrid);
            app.YDataDropDown.Items = {'Altitude', 'Aceleration', 'Speed'};
            app.YDataDropDown.ItemsData = {'Altitude (m)', 'Aceleration (m/s2)', 'Speed (m/s)'};
            app.YDataDropDown.Value = 'Altitude (m)';
            app.FileLoggingPanel = uipanel(app.GridLayout);
            app.FileLoggingPanel.Title = 'File Logging';
            app.FileLoggingGrid = uigridlayout(app.FileLoggingPanel);
            app.FilenameEditField_2Label = uilabel(app.FileLoggingGrid);
            app.FilenameEditField_2Label.Text = 'Filename';
            app.FilenameEditField = uieditfield(app.FileLoggingGrid, 'text');
            app.FilenameEditField.Value = 'GPS_data.txt';
            app.FileSelectButton = uibutton(app.FileLoggingGrid, 'push');
            app.FileSelectButton.Icon = fullfile(pathToMLAPP, 'open.svg');
            app.FileSelectButton.Text = '';
            app.StartButton = uibutton(app.FileLoggingGrid, 'push');
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.FileLoggingGrid, 'push');
            app.StopButton.Enable = 'off';
            app.StopButton.Text = 'Stop';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SerialPortGPSApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end